classdef FiberLaserGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Beta20083e24psmLabel   matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        SliderLabel            matlab.ui.control.Label
        StartSimulationButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % 自定义属性（存储模拟参数和数据）
        SimParams           struct
        PulseParams         struct
    end

    methods (Access = private)
        % 初始化函数
        function setup(app)
            % 创建界面
            app.UIFigure = uifigure('Name', 'Fiber Laser Simulator');
            app.UIFigure.Position = [100 100 800 600];
            
            % 控件
            app.StartButton = uibutton(app.UIFigure, 'push',...
                'Position', [20 550 100 30],...
                'Text', 'Start',...
                'ButtonPushedFcn', @(btn,event) startSimulation(app));
            
            app.Beta2Slider = uislider(app.UIFigure,...
                'Position', [20 500 200 3],...
                'Limits', [-1e-24 1e-24],...
                'Value', -0.083e-24,...
                'ValueChangedFcn', @(sld,event) updateBeta2(app, sld.Value));
            
            app.Beta2Label = uilabel(app.UIFigure,...
                'Position', [20 520 200 20],...
                'Text', sprintf('Beta2: %.2e ps²/m', app.SimParams.beta2));
            
            app.ResultsAxes = uiaxes(app.UIFigure,...
                'Position', [200 100 550 450]);
        end
        
        % 更新Beta2参数
        function updateBeta2(app, newBeta2)
            app.SimParams.beta2 = newBeta2;
            app.Beta2Label.Text = sprintf('Beta2: %.2e ps²/m', newBeta2);
        end
        
        % 启动仿真
        function startSimulation(app)
            % 初始化脉冲
            [app.SimParams, app.PulseParams] = initialize_parameters();
            app.PulseParams = initialize_pulse(app.SimParams, app.PulseParams);
            
            % 执行传播
            [app.PulseParams, app.SimParams] = run_cavity_loop(app.SimParams, app.PulseParams);
            
            % 更新绘图
            plot(app.ResultsAxes, app.PulseParams.z_L, app.PulseParams.E_L*1e9);
            xlabel(app.ResultsAxes, 'Position (m)');
            ylabel(app.ResultsAxes, 'Pulse Energy (nJ)');
            title(app.ResultsAxes, '脉冲能量演化');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'ResultsAxes';
            app.StartSimulationButton = uibutton(app.UIFigure, 'push');
            app.StartSimulationButton.Tag = 'StartButton';
            app.StartSimulationButton.Text = 'Start Simulation';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-1e-24 1e-24];
            app.Slider.Tag = 'Beta2Slider';
            app.Slider.Value = -8.3e-26;
            app.Beta20083e24psmLabel = uilabel(app.UIFigure);
            app.Beta20083e24psmLabel.Tag = 'Beta2Label';
            app.Beta20083e24psmLabel.Text = 'Beta2: -0.083e-24 ps²/m';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FiberLaserGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end